classdef new_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        DataDirectoryEditFieldLabel    matlab.ui.control.Label
        DataDirectoryEditField         matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        DetectionDropDownLabel         matlab.ui.control.Label
        DetectionDropDown              matlab.ui.control.DropDown
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        ExtractionDropDownLabel        matlab.ui.control.Label
        ExtractionDropDown             matlab.ui.control.DropDown
        LoadExistingButton             matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        OutputDirectoryEditField       matlab.ui.control.EditField
        RunClassifierButton            matlab.ui.control.Button
        UpdatePreviewButton            matlab.ui.control.Button
        ExportBoFCheckBox              matlab.ui.control.CheckBox
        ExportVisualFeaturesCheckBox   matlab.ui.control.CheckBox
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        PopOutButton                   matlab.ui.control.Button
        SelectSetDropDownLabel         matlab.ui.control.Label
        SelectSetDropDown              matlab.ui.control.DropDown
        RightPanel                     matlab.ui.container.Panel
        InfoLabel                      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            switch app.DetectionDropDown.Value
                case "FAST"
                    run("detect_options\d_fast.mlapp");
                case "MinEig"
                    run("detect_options\d_mineig.mlapp");
                case "Harris"
                    run("detect_options\d_harris.mlapp");
                case "SURF"
                    run("detect_options\d_surf.mlapp");
                case "KAZE"
                    run("detect_options\d_kaze.mlapp");
                case "BRISK"
                    run("detect_options\d_brisk.mlapp");
                case "MSER"
                    run("detect_options\d_mser.mlapp");
                case "ORB"
                    run("detect_options\d_orb.mlapp");
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataDirectoryEditFieldLabel = uilabel(app.LeftPanel);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.DataDirectoryEditField.Value = 'c:\';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.Text = '...';
            app.DetectionDropDownLabel = uilabel(app.LeftPanel);
            app.DetectionDropDownLabel.Text = 'Detection';
            app.DetectionDropDown = uidropdown(app.LeftPanel);
            app.DetectionDropDown.Items = {'FAST', 'MinEig', 'Harris', 'SURF', 'KAZE', 'BRISK', 'MSER', 'ORB'};
            app.DetectionDropDown.Value = 'FAST';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.Text = '...';
            app.ExtractionDropDownLabel = uilabel(app.LeftPanel);
            app.ExtractionDropDownLabel.Text = 'Extraction';
            app.ExtractionDropDown = uidropdown(app.LeftPanel);
            app.ExtractionDropDown.Items = {'HOG', 'SURF', 'KAZE', 'FREAK', 'BRISK', 'ORB'};
            app.ExtractionDropDown.Value = 'HOG';
            app.LoadExistingButton = uibutton(app.LeftPanel, 'push');
            app.LoadExistingButton.Text = 'Load Existing';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.Text = '...';
            app.OutputDirectoryEditFieldLabel = uilabel(app.LeftPanel);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.OutputDirectoryEditField.Value = 'c:\';
            app.RunClassifierButton = uibutton(app.LeftPanel, 'push');
            app.RunClassifierButton.Text = 'Run Classifier';
            app.UpdatePreviewButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePreviewButton.Text = 'Update Preview';
            app.ExportBoFCheckBox = uicheckbox(app.LeftPanel);
            app.ExportBoFCheckBox.Text = 'Export BoF';
            app.ExportVisualFeaturesCheckBox = uicheckbox(app.LeftPanel);
            app.ExportVisualFeaturesCheckBox.Text = 'Export Visual Features';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PopOutButton = uibutton(app.CenterPanel, 'push');
            app.PopOutButton.Text = 'Pop Out';
            app.SelectSetDropDownLabel = uilabel(app.CenterPanel);
            app.SelectSetDropDownLabel.Text = 'Select Set';
            app.SelectSetDropDown = uidropdown(app.CenterPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.InfoLabel = uilabel(app.RightPanel);
            app.InfoLabel.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_design

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end